classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ExitButton                   matlab.ui.control.Button
        GetStartedButton             matlab.ui.control.Button
        SpeakingSignbyLanceBaeLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ColumnKnobLabel              matlab.ui.control.Label
        ColumnKnob                   matlab.ui.control.Knob
        SizeLabel                    matlab.ui.control.Label
        SizeKnob                     matlab.ui.control.Knob
        RowKnob_3Label               matlab.ui.control.Label
        RowKnob                      matlab.ui.control.Knob
        StartEvaluatingButton        matlab.ui.control.StateButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cameraOn % Description
        row = 1
        col = 1
        side = 200
        evaluating = 0
        nnet % Description
    end
    
    methods (Access = private)
        
        function drawSquare(app)
            rectangle(app.UIAxes, 'Position', [app.row, app.col, app.side, app.side], 'Edgecolor', 'g', 'LineWidth', 2);
        end
        
        function updateImage(app, cam)
            frame = snapshot(cam);
            frame = flipud(frame);
            im = image(app.UIAxes, frame);
            axis(app.UIAxes, 'image');
            drawSquare(app);
            if app.evaluating == 1
                toClassify = imcrop(frame,[app.row, app.col, app.side, app.side]);
                toClassify = rgb2gray(toClassify);
                toClassify = imresize(toClassify, [28, 28]);
                imshow(toClassify);
                result = classify(app.nnet, toClassify);
                app.Label.Text = result;
                
            end
%             preview(cam, im);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.cameraOn = false;
            close all;
            delete(app);
        end

        % Button pushed function: GetStartedButton
        function GetStartedButtonPushed(app, event)
            cam = webcam('EOS Webcam Utility');
            app.cameraOn = true;
            load("CNN Info\Model 1\CNN.mat");
            app.nnet = trainedNetwork_1;
            while app.cameraOn
                updateImage(app, cam);
                pause(0.3);
            end
            delete(app);
        end

        % Value changing function: ColumnKnob
        function ColumnKnobValueChanging(app, event)
            changingValue = event.Value;
            height = 1024 - app.side;
            app.row = floor(changingValue * height / 100);
        end

        % Value changing function: RowKnob
        function RowKnobValueChanging(app, event)
            changingValue = event.Value;
            length = 576 - app.side;
            app.col = floor(changingValue * length / 100);
        end

        % Value changing function: SizeKnob
        function SizeKnobValueChanging(app, event)
            changingValue = event.Value;
            app.side = floor(changingValue * 3) + 200;
        end

        % Value changed function: StartEvaluatingButton
        function StartEvaluatingButtonValueChanged(app, event)
            value = app.StartEvaluatingButton.Value;
            app.evaluating = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.GetStartedButton = uibutton(app.UIFigure, 'push');
            app.GetStartedButton.ButtonPushedFcn = createCallbackFcn(app, @GetStartedButtonPushed, true);
            app.GetStartedButton.Text = 'Get Started';
            app.SpeakingSignbyLanceBaeLabel = uilabel(app.UIFigure);
            app.SpeakingSignbyLanceBaeLabel.Text = 'Speaking Sign by Lance Bae';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '?';
            app.ColumnKnobLabel = uilabel(app.UIFigure);
            app.ColumnKnobLabel.Text = 'Column';
            app.ColumnKnob = uiknob(app.UIFigure, 'continuous');
            app.ColumnKnob.MajorTicks = [0 100];
            app.ColumnKnob.MajorTickLabels = {'left', 'right'};
            app.ColumnKnob.ValueChangingFcn = createCallbackFcn(app, @ColumnKnobValueChanging, true);
            app.ColumnKnob.MinorTicks = [];
            app.SizeLabel = uilabel(app.UIFigure);
            app.SizeLabel.Text = 'Size';
            app.SizeKnob = uiknob(app.UIFigure, 'continuous');
            app.SizeKnob.MajorTicks = [0 100];
            app.SizeKnob.MajorTickLabels = {'small', 'big'};
            app.SizeKnob.ValueChangingFcn = createCallbackFcn(app, @SizeKnobValueChanging, true);
            app.SizeKnob.MinorTicks = [];
            app.RowKnob_3Label = uilabel(app.UIFigure);
            app.RowKnob_3Label.Text = 'Row';
            app.RowKnob = uiknob(app.UIFigure, 'continuous');
            app.RowKnob.MajorTicks = [0 100];
            app.RowKnob.MajorTickLabels = {'up', 'down'};
            app.RowKnob.ValueChangingFcn = createCallbackFcn(app, @RowKnobValueChanging, true);
            app.RowKnob.MinorTicks = [];
            app.StartEvaluatingButton = uibutton(app.UIFigure, 'state');
            app.StartEvaluatingButton.ValueChangedFcn = createCallbackFcn(app, @StartEvaluatingButtonValueChanged, true);
            app.StartEvaluatingButton.Text = 'Start Evaluating';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end